classdef interfacedesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SignalAnalysisTab               matlab.ui.container.Tab
        ViewallButton                   matlab.ui.control.Button
        Button0_7                       matlab.ui.control.Button
        Button8_15                      matlab.ui.control.Button
        Button16_23                     matlab.ui.control.Button
        Button24_31                     matlab.ui.control.Button
        ModesButtonGroup                matlab.ui.container.ButtonGroup
        ReferencechannelEditField       matlab.ui.control.NumericEditField
        ReferencechannelEditFieldLabel  matlab.ui.control.Label
        NotchBandpasshighfqButton       matlab.ui.control.RadioButton
        BandpasshighfqButton            matlab.ui.control.RadioButton
        NotchBandpasslowfqButton        matlab.ui.control.RadioButton
        BandpasslowfqButton             matlab.ui.control.RadioButton
        NotchButton_2                   matlab.ui.control.RadioButton
        Button9                         matlab.ui.control.Button
        Button10                        matlab.ui.control.Button
        Button11                        matlab.ui.control.Button
        Button12                        matlab.ui.control.Button
        Button13                        matlab.ui.control.Button
        Button14                        matlab.ui.control.Button
        Button15                        matlab.ui.control.Button
        Button16                        matlab.ui.control.Button
        Button17                        matlab.ui.control.Button
        Button18                        matlab.ui.control.Button
        Button23                        matlab.ui.control.Button
        Button19                        matlab.ui.control.Button
        Button20                        matlab.ui.control.Button
        Button22                        matlab.ui.control.Button
        Button21                        matlab.ui.control.Button
        Button4                         matlab.ui.control.Button
        Button5                         matlab.ui.control.Button
        Button6                         matlab.ui.control.Button
        Button7                         matlab.ui.control.Button
        Button8                         matlab.ui.control.Button
        Button26                        matlab.ui.control.Button
        Button25                        matlab.ui.control.Button
        Button24                        matlab.ui.control.Button
        Button27                        matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        Button2                         matlab.ui.control.Button
        Button3                         matlab.ui.control.Button
        Button29                        matlab.ui.control.Button
        Button1                         matlab.ui.control.Button
        Button30                        matlab.ui.control.Button
        Button31                        matlab.ui.control.Button
        Button28                        matlab.ui.control.Button
        Button0                         matlab.ui.control.Button
        KineticanalysisTab              matlab.ui.container.Tab
        StopButton                      matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        ChannelEditField                matlab.ui.control.NumericEditField
        ChannelEditFieldLabel           matlab.ui.control.Label
        PlayButton                      matlab.ui.control.Button
        LoadvideofileButton             matlab.ui.control.Button
        TimeSpinner                     matlab.ui.control.Spinner
        TimeSpinnerLabel                matlab.ui.control.Label
        StartSpinner                    matlab.ui.control.Spinner
        StartSpinnerLabel               matlab.ui.control.Label
        fpsSpinner                      matlab.ui.control.Spinner
        fpsSpinnerLabel                 matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % assigned variables

        % Parameters

        % fn = fs/2;     % Nyquist freq
        % f0 = 6;        % order
        f_notch = 50;   % notch filter freq
        fs = 30000;     % sampling freq
        f1 = 1;         % lower threshold of low freq bp filter
        f2 = 100;       % upper threshold of low freq bp filter
        f3 = 50;        % lower threshold of high freq bp filter
        f4 = 4000;      % lower threshold of high freq bp filter

        % Load variables from main script
        amplifier_data = evalin('base', 'amplifier_data');
        t = evalin('base', 't');

        % Video variables
        % stopvideo = true;

        % VideoFileName
        filename;
        % pathname;
        % get;

    end


    methods (Access = private)

        function filtered_data = filter_signal(app, data)
            % Filters the data based on the mode selected.

            if app.NotchButton_2.Value
                filtered_data = notch(data, app.f_notch, app.fs);

            elseif app.BandpasslowfqButton.Value
                filtered_data = filter_bandpass(data, app.f1, app.f2, app.fs);

            elseif app.NotchBandpasslowfqButton.Value
                data_notch = notch(data, app.f_notch, app.fs);
                filtered_data = filter_bandpass(data_notch, app.f1, app.f2, app.fs);

            elseif app.BandpasshighfqButton.Value
                filtered_data = filter_bandpass(data, app.f3, app.f4, app.fs);

            elseif app.NotchBandpasshighfqButton.Value
                data_notch = notch(data, app.f_notch, app.fs);
                filtered_data = filter_bandpass(data_notch, app.f3, app.f4, app.fs);
            end
        end


        function single_channel_button(app, channel)
            % Apply filter to the data from a single channel and plot the
            % output in pop-out window.

            unfiltered_data = app.amplifier_data(channel, 1:end);
            single_channel_data = app.filter_signal(unfiltered_data);

            % Plotting
            figure; hold on;
            tiledlayout (1,1);
            plot(app.t(1:end), single_channel_data(1:end));
            title(sprintf('Channel %d recording', channel));
            xlabel('time (s)');
            ylabel('p.d.(mV)');

        end


        function video_channel_button(app, channel)
            % Apply filter to the data from a single channel and plot the
            % output on axes below the video.

            unfiltered_data = app.amplifier_data(channel, 1:end);
            video_single_data = app.filter_signal(unfiltered_data);

            % Plotting
            plot(app.UIAxes2, app.t(1:end), video_single_data(1:end));
            % @@@ add title and axis labels

        end


        function multiple_channel_button(app, start_channel, first, second, third, fourth, fifth, sixth, seventh, end_channel)
            % Apply filter to the data from multiple channels and plot the
            % output in pop-out window.

            unfiltered_data = app.amplifier_data;
            multiple_channel_data = app.filter_signal(unfiltered_data);
            

            % Plotting
            figure; hold on;

          % if all_channels == 0
                
               tiledlayout(8, 1)
               nexttile
               
               plot(app.t(1:end), multiple_channel_data(start_channel, 1:end));
               xlabel('time (s)');
               ylabel('p.d.(mV)');
               title('Multiple channel recordings')
               nexttile
               
               plot(app.t(1:end), multiple_channel_data(first, 1:end));
               xlabel('time (s)');
               ylabel('p.d.(mV)');
               nexttile
               
                
               plot(app.t(1:end), multiple_channel_data(second, 1:end));
               xlabel('time (s)');
               ylabel('p.d.(mV)');
               nexttile
               
               
               plot(app.t(1:end), multiple_channel_data(third, 1:end));
               xlabel('time (s)');
               ylabel('p.d.(mV)');
               nexttile
               
               plot(app.t(1:end), multiple_channel_data(fourth, 1:end));
               xlabel('time (s)');
               ylabel('p.d.(mV)');
               nexttile
              
               
               plot(app.t(1:end), multiple_channel_data(fifth, 1:end));
               xlabel('time (s)');
               ylabel('p.d.(mV)');
               nexttile
               
               plot(app.t(1:end), multiple_channel_data(sixth, 1:end));
               xlabel('time (s)');
               ylabel('p.d.(mV)');
               nexttile
               
               plot(app.t(1:end), multiple_channel_data(seventh, 1:end));
               xlabel('time (s)');
               ylabel('p.d.(mV)');
               nexttile
               
               plot(app.t(1:end), multiple_channel_data(end_channel, 1:end));
               xlabel('time (s)');
               ylabel('p.d.(mV)');
               
           end 
        
        
        function view_all_button(app)
            
               unfiltered_data = app.amplifier_data;
               multiple_channel_data = app.filter_signal(unfiltered_data);
            
               tiledlayout(8, 4)

               for x=1:32
                   nexttile;
                   plot(app.t(1:end), multiple_channel_data(x, 1:end));
                   xlabel('time (s)');
                   ylabel('p.d.(mV)');
               end
               
              end


        %     function reference_channel_data = reference_channel_button (app, channel)
        %
        %             if app.NotchButton_2.Value
        %                 reference_channel_data = notch (app.amplifier_data, app.f_notch, app.fs);
        %
        %             elseif app.BandpasslowfqButton.Value
        %                 reference_channel_data = filter_bandpass(app.amplifier_data, app.f1, app.f2, app.fs);
        %
        %             elseif app.NotchBandpasslowfqButton.Value
        %                 reference_channel_data = filter_bandpass(amplifier_data_notch, app.f1, app.f2, app.fs);
        %
        %             elseif app.BandpasshighfqButton.Value
        %                 reference_channel_data = filter_bandpass(app.amplifier_data, app.f3, app.f4, app.fs);
        %
        %             elseif app.NotchBandpasshighfqButton.Value
        %                 reference_channel_data = filter_bandpass(amplifier_data_notch, app.f3, app.f4, app.fs);
        %
        %             end
        %             %plotting
        %
        %
        %              plot(app.t(1:end), difference (channel,1:end));
        %     end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            channel = 32;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button0_7
        function Button0_7Pushed(app, event)
            start_channel = 1;
            second = 32;
            third = 31;
            fourth = 3;
            fifth = 30;
            sixth = 4;
            seventh = 29;
            end_channel = 29;
            
            all_channels = 0;

            app.multiple_channel_button(start_channel, second, third, fourth, fifth, fifth, sixth, seventh, end_channel, all_channels)
        end

        % Button pushed function: Button8_15
        function Button8_15Pushed(app, event)
            start_channel = 5;
            second = 28;
            third = 6;
            fourth = 27;
            fifth = 7;
            sixth = 26;
            seventh = 8;
            end_channel = 25;
            
            all_channels = 0;

            app.multiple_channel_button(start_channel, second, third, fourth, fifth, fifth, sixth, seventh, end_channel, all_channels)
        end

        % Button pushed function: Button16_23
        function Button16_23Pushed(app, event)
            start_channel = 9;
            second = 24;
            third = 10;
            fourth = 23;
            fifth = 11;
            sixth = 22;
            seventh = 12;
            end_channel = 21;
            
            all_channels = 0;

            app.multiple_channel_button(start_channel, second, third, fourth, fifth, fifth, sixth, seventh, end_channel, all_channels)
        end

        % Button pushed function: Button24_31
        function Button24_31Pushed(app, event)
            start_channel = 13;
            second = 20;
            third = 14;
            fourth = 19;
            fifth = 15;
            sixth = 18;
            seventh = 16;
            end_channel = 17;
            
            all_channels = 0;

            app.multiple_channel_button(start_channel, second, third, fourth, fifth, fifth, sixth, seventh, end_channel, all_channels)
        end

        % Button pushed function: ViewallButton
        function ViewallButtonPushed(app, event)
            app.view_all_button;
             
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            channel = 2;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            channel = 31;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button4
        function Button4Pushed(app, event)
            channel = 3;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button5
        function Button5Pushed(app, event)
            channel = 30;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button6
        function Button6Pushed(app, event)
            channel = 4;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button7
        function Button7Pushed(app, event)
            channel = 29;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button8
        function Button8Pushed(app, event)
            channel = 5;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button9
        function Button9Pushed(app, event)
            channel = 28;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button10
        function Button10Pushed(app, event)
            channel = 6;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button11
        function Button11Pushed(app, event)
            channel = 27;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button12
        function Button12Pushed(app, event)
            channel = 7;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button13
        function Button13Pushed(app, event)
            channel = 26;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button14
        function Button14Pushed(app, event)
            channel = 8;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button15
        function Button15Pushed(app, event)
            channel = 25;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button16
        function Button16Pushed(app, event)
            channel = 9;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button17
        function Button17Pushed(app, event)
            channel = 24;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button18
        function Button18Pushed(app, event)
            channel = 10;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button19
        function Button19Pushed(app, event)
            channel = 23;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button20
        function Button20Pushed(app, event)
            channel = 11;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button21
        function Button21Pushed(app, event)
            channel = 22;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button22
        function Button22Pushed(app, event)
            channel = 12;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button23
        function Button23Pushed(app, event)
            channel = 21;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button24
        function Button24Pushed(app, event)
            channel = 13;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button25
        function Button25Pushed(app, event)
            channel = 20;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button26
        function Button26Pushed(app, event)
            channel = 14;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button27
        function Button27Pushed(app, event)
            channel = 19;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button28
        function Button28Pushed(app, event)
            channel = 15;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button29
        function Button29Pushed(app, event)
            channel = 18;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button30
        function Button30Pushed(app, event)
            channel = 16;
            app.single_channel_button(channel)
        end

        % Button pushed function: Button31
        function Button31Pushed(app, event)
            channel = 17;
            app.single_channel_button(channel)
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % Initialises playback of imported video.

            % @@@ error handling
            videoReader = VideoReader(app.filename);

            ax = app.UIAxes3;
            fps = app.fpsSpinner.Value;

            % @@@ stop and pause
            while hasFrame(videoReader) % && app.stopvideo==false
%                 get(app.StopButtonPushed, 'on');
%
%                 if stop_video
%                     break;
%                 end

                % display next frame
                drawnow()
                vidFrame = readFrame(videoReader);
                image(vidFrame, 'Parent', ax);
                set(ax, 'Visible', 'off');

                pause(1/fps);

            end
        end

        % Button pushed function: LoadvideofileButton
        function LoadvideofileButtonPushed(app, event)
            % Load mp4 video file into the interface.

            [app.filename] = uigetfile('*.mp4', 'Select an .mp4 video File', 'MultiSelect', 'off');

            % @@@ error handling
            %if isequal(filename)
            %    disp('No file selected')
            %else
            %    disp('User selected', fullfile(filename))
            %end

            app.filename = fullfile(app.filename);
        end

        % Value changed function: ChannelEditField
        function ChannelEditFieldValueChanged(app, event)
            % When 'channel' field edited, update value of class attribute.

            channel = app.ChannelEditField.Value;
            app.video_channel_button (channel);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Set a class attribute to track the state of the button.

            % @@@

            app.StopButtonPushed = 'on';
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            % Set a class attribute to track the state of the button.

            % @@@
        end

        % Value changed function: ReferencechannelEditField
        function ReferencechannelEditFieldValueChanged(app, event)
            channel2 = app.ReferencechannelEditField.Value;

            app.ReferencechannelEditField.Value (channel2);

        end

        % Button pushed function: Button0
        function Button0Pushed(app, event)
             channel = 1;
            app.single_channel_button(channel)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SignalAnalysisTab = uitab(app.TabGroup);
            app.SignalAnalysisTab.Title = 'Signal Analysis';
            app.Button0 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button0.ButtonPushedFcn = createCallbackFcn(app, @Button0Pushed, true);
            app.Button0.Text = '0';
            app.Button28 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button28.ButtonPushedFcn = createCallbackFcn(app, @Button28Pushed, true);
            app.Button28.Text = '28';
            app.Button31 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button31.ButtonPushedFcn = createCallbackFcn(app, @Button31Pushed, true);
            app.Button31.Text = '31';
            app.Button30 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button30.ButtonPushedFcn = createCallbackFcn(app, @Button30Pushed, true);
            app.Button30.Text = '30';
            app.Button1 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = '1';
            app.Button29 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button29.ButtonPushedFcn = createCallbackFcn(app, @Button29Pushed, true);
            app.Button29.Text = '29';
            app.Button3 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Text = '3';
            app.Button2 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = '2';
            app.Image = uiimage(app.SignalAnalysisTab);
            app.Image.ImageSource = 'Neural interface zoom.png';
            app.Button27 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button27.ButtonPushedFcn = createCallbackFcn(app, @Button27Pushed, true);
            app.Button27.Text = '27';
            app.Button24 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button24.ButtonPushedFcn = createCallbackFcn(app, @Button24Pushed, true);
            app.Button24.Text = '24';
            app.Button25 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button25.ButtonPushedFcn = createCallbackFcn(app, @Button25Pushed, true);
            app.Button25.Text = '25';
            app.Button26 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button26.ButtonPushedFcn = createCallbackFcn(app, @Button26Pushed, true);
            app.Button26.Text = '26';
            app.Button8 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button8.ButtonPushedFcn = createCallbackFcn(app, @Button8Pushed, true);
            app.Button8.Text = '8';
            app.Button7 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @Button7Pushed, true);
            app.Button7.Text = '7';
            app.Button6 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @Button6Pushed, true);
            app.Button6.Text = '6';
            app.Button5 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Button5Pushed, true);
            app.Button5.Text = '5';
            app.Button4 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button4.Text = '4';
            app.Button21 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button21.ButtonPushedFcn = createCallbackFcn(app, @Button21Pushed, true);
            app.Button21.Text = '21';
            app.Button22 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button22.ButtonPushedFcn = createCallbackFcn(app, @Button22Pushed, true);
            app.Button22.Text = '22';
            app.Button20 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button20.ButtonPushedFcn = createCallbackFcn(app, @Button20Pushed, true);
            app.Button20.Text = '20';
            app.Button19 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button19.ButtonPushedFcn = createCallbackFcn(app, @Button19Pushed, true);
            app.Button19.Text = '19';
            app.Button23 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button23.ButtonPushedFcn = createCallbackFcn(app, @Button23Pushed, true);
            app.Button23.Text = '23';
            app.Button18 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button18.ButtonPushedFcn = createCallbackFcn(app, @Button18Pushed, true);
            app.Button18.Text = '18';
            app.Button17 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button17.ButtonPushedFcn = createCallbackFcn(app, @Button17Pushed, true);
            app.Button17.Text = '17';
            app.Button16 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button16.ButtonPushedFcn = createCallbackFcn(app, @Button16Pushed, true);
            app.Button16.Text = '16';
            app.Button15 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button15.ButtonPushedFcn = createCallbackFcn(app, @Button15Pushed, true);
            app.Button15.Text = '15';
            app.Button14 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button14.ButtonPushedFcn = createCallbackFcn(app, @Button14Pushed, true);
            app.Button14.Text = '14';
            app.Button13 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button13.ButtonPushedFcn = createCallbackFcn(app, @Button13Pushed, true);
            app.Button13.Text = '13';
            app.Button12 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button12.ButtonPushedFcn = createCallbackFcn(app, @Button12Pushed, true);
            app.Button12.Text = '12';
            app.Button11 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button11.ButtonPushedFcn = createCallbackFcn(app, @Button11Pushed, true);
            app.Button11.Text = '11';
            app.Button10 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button10.ButtonPushedFcn = createCallbackFcn(app, @Button10Pushed, true);
            app.Button10.Text = '10';
            app.Button9 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @Button9Pushed, true);
            app.Button9.Text = '9';
            app.ModesButtonGroup = uibuttongroup(app.SignalAnalysisTab);
            app.ModesButtonGroup.Title = 'Modes';
            app.NotchButton_2 = uiradiobutton(app.ModesButtonGroup);
            app.NotchButton_2.Text = 'Notch';
            app.NotchButton_2.Value = true;
            app.BandpasslowfqButton = uiradiobutton(app.ModesButtonGroup);
            app.BandpasslowfqButton.Text = 'Bandpass (lowfq)';
            app.NotchBandpasslowfqButton = uiradiobutton(app.ModesButtonGroup);
            app.NotchBandpasslowfqButton.Text = 'Notch + Bandpass (lowfq)';
            app.BandpasshighfqButton = uiradiobutton(app.ModesButtonGroup);
            app.BandpasshighfqButton.Text = 'Bandpass (highfq)';
            app.NotchBandpasshighfqButton = uiradiobutton(app.ModesButtonGroup);
            app.NotchBandpasshighfqButton.Text = 'Notch + Bandpass (highfq)';
            app.ReferencechannelEditFieldLabel = uilabel(app.ModesButtonGroup);
            app.ReferencechannelEditFieldLabel.Text = 'Reference channel';
            app.ReferencechannelEditField = uieditfield(app.ModesButtonGroup, 'numeric');
            app.ReferencechannelEditField.ValueChangedFcn = createCallbackFcn(app, @ReferencechannelEditFieldValueChanged, true);
            app.Button24_31 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button24_31.ButtonPushedFcn = createCallbackFcn(app, @Button24_31Pushed, true);
            app.Button24_31.Text = '24 -31';
            app.Button16_23 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button16_23.ButtonPushedFcn = createCallbackFcn(app, @Button16_23Pushed, true);
            app.Button16_23.Text = '16 - 23';
            app.Button8_15 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button8_15.ButtonPushedFcn = createCallbackFcn(app, @Button8_15Pushed, true);
            app.Button8_15.Text = '8 - 15';
            app.Button0_7 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button0_7.ButtonPushedFcn = createCallbackFcn(app, @Button0_7Pushed, true);
            app.Button0_7.Text = '0 - 7';
            app.ViewallButton = uibutton(app.SignalAnalysisTab, 'push');
            app.ViewallButton.ButtonPushedFcn = createCallbackFcn(app, @ViewallButtonPushed, true);
            app.ViewallButton.Text = 'View all';
            app.KineticanalysisTab = uitab(app.TabGroup);
            app.KineticanalysisTab.Title = 'Kinetic analysis';
            app.UIAxes3 = uiaxes(app.KineticanalysisTab);
            app.UIAxes3.XTick = [];
            app.UIAxes3.XTickLabel = '';
            app.UIAxes3.YTick = [];
            app.UIAxes3.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.KineticanalysisTab);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.Slider = uislider(app.KineticanalysisTab);
            app.FilenameEditFieldLabel = uilabel(app.KineticanalysisTab);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.KineticanalysisTab, 'text');
            app.fpsSpinnerLabel = uilabel(app.KineticanalysisTab);
            app.fpsSpinnerLabel.Text = 'fps';
            app.fpsSpinner = uispinner(app.KineticanalysisTab);
            app.fpsSpinner.Value = 120;
            app.StartSpinnerLabel = uilabel(app.KineticanalysisTab);
            app.StartSpinnerLabel.Text = 'Start ';
            app.StartSpinner = uispinner(app.KineticanalysisTab);
            app.TimeSpinnerLabel = uilabel(app.KineticanalysisTab);
            app.TimeSpinnerLabel.Text = 'Time';
            app.TimeSpinner = uispinner(app.KineticanalysisTab);
            app.LoadvideofileButton = uibutton(app.KineticanalysisTab, 'push');
            app.LoadvideofileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadvideofileButtonPushed, true);
            app.LoadvideofileButton.Text = 'Load video file';
            app.PlayButton = uibutton(app.KineticanalysisTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ChannelEditFieldLabel = uilabel(app.KineticanalysisTab);
            app.ChannelEditFieldLabel.Text = 'Channel ';
            app.ChannelEditField = uieditfield(app.KineticanalysisTab, 'numeric');
            app.ChannelEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelEditFieldValueChanged, true);
            app.PauseButton = uibutton(app.KineticanalysisTab, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.KineticanalysisTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfacedesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end